classdef Main < matlab.apps.AppBase
%MAIN Serial-port monitor with up to three live graphs.
%   readData stores the text stream. MonitorGraph draws, summarizes, and exports it.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SettingsMenu                 matlab.ui.container.Menu
        SerialPortConfigurationMenu  matlab.ui.container.Menu
        DataConfigurationMenu        matlab.ui.container.Menu
        QuitMenu                     matlab.ui.container.Menu
        SerialPortActionsMenu        matlab.ui.container.Menu
        InitializeSPCMenu            matlab.ui.container.Menu
        DisconnectSPCMenu            matlab.ui.container.Menu
        CheckSPCMenu                 matlab.ui.container.Menu
        ResetMenu                    matlab.ui.container.Menu
        ResetDataMenu                matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        Graph1Tab                    matlab.ui.container.Tab
        Y_LimEditField               matlab.ui.control.EditField
        Y_LimEditFieldLabel          matlab.ui.control.Label
        X_LimEditField               matlab.ui.control.EditField
        X_LimEditFieldLabel          matlab.ui.control.Label
        ColorsEditField              matlab.ui.control.EditField
        ColorsEditFieldLabel         matlab.ui.control.Label
        LegendSwitch                 matlab.ui.control.Switch
        LegendSwitchLabel            matlab.ui.control.Label
        GridSwitch                   matlab.ui.control.Switch
        GridSwitchLabel              matlab.ui.control.Label
        EnableLimitsCheckBox         matlab.ui.control.CheckBox
        SaveascsvButton              matlab.ui.control.Button
        SaveasjpgButton              matlab.ui.control.Button
        WatchDataCheckBox            matlab.ui.control.CheckBox
        CalculateStatisticsDataCheckBox  matlab.ui.control.CheckBox
        UITable                      matlab.ui.control.Table
        TypeColorsseparatedbycommasLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        Graph2Tab                    matlab.ui.container.Tab
        Y_LimEditField_2             matlab.ui.control.EditField
        Y_LimEditField_2Label        matlab.ui.control.Label
        X_LimEditField_2             matlab.ui.control.EditField
        X_LimEditField_2Label        matlab.ui.control.Label
        ColorsEditField_2            matlab.ui.control.EditField
        ColorsEditField_2Label       matlab.ui.control.Label
        LegendSwitch_2               matlab.ui.control.Switch
        LegendSwitch_2Label          matlab.ui.control.Label
        GridSwitch_2                 matlab.ui.control.Switch
        GridSwitch_2Label            matlab.ui.control.Label
        EnableLimitsCheckBox_2       matlab.ui.control.CheckBox
        SaveascsvButton_2            matlab.ui.control.Button
        SaveasjpgButton_2            matlab.ui.control.Button
        WatchDataCheckBox_2          matlab.ui.control.CheckBox
        CalculateStatisticsDataCheckBox_2  matlab.ui.control.CheckBox
        UITable_2                    matlab.ui.control.Table
        TypeColorsseparatedbycommasLabel_2  matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        Graph3Tab                    matlab.ui.container.Tab
        Y_LimEditField_3             matlab.ui.control.EditField
        Y_LimEditField_3Label        matlab.ui.control.Label
        X_LimEditField_3             matlab.ui.control.EditField
        X_LimEditField_3Label        matlab.ui.control.Label
        ColorsEditField_3            matlab.ui.control.EditField
        ColorsEditField_3Label       matlab.ui.control.Label
        LegendSwitch_3               matlab.ui.control.Switch
        LegendSwitch_3Label          matlab.ui.control.Label
        GridSwitch_3                 matlab.ui.control.Switch
        GridSwitch_3Label            matlab.ui.control.Label
        EnableLimitsCheckBox_3       matlab.ui.control.CheckBox
        SaveascsvButton_3            matlab.ui.control.Button
        SaveasjpgButton_3            matlab.ui.control.Button
        WatchDataCheckBox_3          matlab.ui.control.CheckBox
        CalculateStatisticsDataCheckBox_3  matlab.ui.control.CheckBox
        UITable_3                    matlab.ui.control.Table
        TypeColorsseparatedbycommasLabel_3  matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DialogApp % Dialog box app
        SPC_main 
        Data_main
    end
    
    properties (Access = private)
        MCU % Microcontroller
        Initialization_state =0;  % State of MCU
        
        %Timers
        t1
        t2
        t3
        
        % Graphs Colors
        colors = ["b","r","g","m","k","y"];
        
        % Axis limits
        ax1
        ax2
        ax3
    end

    methods (Access = private)
         % Draw Graph 1   
        function draw1(app)
            seriesCount = app.Data_main.graphvariables(1);
            MonitorGraph.render(app.MCU.UserData, app.UIAxes, 1, 0, seriesCount, ...
                app.colors, app.EnableLimitsCheckBox.Value, app.ax1, ...
                app.GridSwitch.Value, app.LegendSwitch.Value);
        end

         % Draw Graph 2
        function draw2(app)
            earlierCount = sum(app.Data_main.graphvariables(1));
            seriesCount = app.Data_main.graphvariables(2);
            MonitorGraph.render(app.MCU.UserData, app.UIAxes_2, 2, earlierCount, seriesCount, ...
                app.colors, app.EnableLimitsCheckBox_2.Value, app.ax2, ...
                app.GridSwitch_2.Value, app.LegendSwitch_2.Value);
        end
         
         % Draw Graph 3
        function draw3(app)
            earlierCount = sum(app.Data_main.graphvariables(1:2));
            seriesCount = app.Data_main.graphvariables(3);
            MonitorGraph.render(app.MCU.UserData, app.UIAxes_3, 3, earlierCount, seriesCount, ...
                app.colors, app.EnableLimitsCheckBox_3.Value, app.ax3, ...
                app.GridSwitch_3.Value, app.LegendSwitch_3.Value);
        end

    end
        
    methods (Access = public)
        % Definition of how many tab shoud be visible
        function update_graphs(app,x)
            switch x
                case 1
                    app.Graph1Tab.Parent=app.TabGroup;
                    app.Graph2Tab.Parent=[];
                    app.Graph3Tab.Parent=[];
             
                case 2
                    app.Graph1Tab.Parent=app.TabGroup;
                    app.Graph2Tab.Parent=app.TabGroup;
                    app.Graph3Tab.Parent=[];
             
                case 3
                    app.Graph1Tab.Parent=app.TabGroup;
                    app.Graph2Tab.Parent=app.TabGroup;
                    app.Graph3Tab.Parent=app.TabGroup;
        
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Timers Setup
            timer_Period = 0.2;
            app.t1 = timer();
            app.t1.ExecutionMode = 'fixedRate';
            app.t1.Period = timer_Period;
            app.t1.TimerFcn =@(~,~)draw1(app);
            app.t2 = timer();
            app.t2.ExecutionMode = 'fixedRate';
            app.t2.Period = timer_Period;
            app.t2.TimerFcn =@(~,~)draw2(app);
            app.t3 = timer();
            app.t3.ExecutionMode = 'fixedRate';
            app.t3.Period = timer_Period;
            app.t3.TimerFcn =@(~,~)draw3(app);


            % SPC Initial Conditions 
            SPC = struct;
            SPC.port ="COM3";
            SPC.baudrate = 115200;
            SPC.parity = "none";
            SPC.databits =8;
            SPC.stopbits =1;
            SPC.flowcontrol ="none";
            SPC.byteorder = "little-endian";
            SPC.timeout =10;
            SPC.terminator ="CR/LF";
            SPC.meas = 0;
            app.SPC_main = SPC;
            
            mcu.UserData = struct("Title","","Headers","","Ylabels","","Data","START","Count",1);
            app.MCU = mcu;

            % Data initial configuration
            Data = struct;
            Data.graphnum = 1;
            Data.graphvariables = 2;
            app.Data_main = Data;
            update_graphs(app,Data.graphnum);
            
            %Axes
            app.ax1 = [0 10 0 10];
            app.ax2 = [0 10 0 10];
            app.ax3 = [0 10 0 10];

            % Graph 
            %1
            app.UITable.Visible = "off";
            app.X_LimEditField.Editable ="off";
            app.Y_LimEditField.Editable = "off";
            %2
            app.UITable_2.Visible = "off";
            app.X_LimEditField_2.Editable ="off";
            app.Y_LimEditField_2.Editable = "off";
            %3
            app.UITable_3.Visible = "off";
            app.X_LimEditField_3.Editable ="off";
            app.Y_LimEditField_3.Editable = "off";
        end

        % Callback function: QuitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            clear app.MCU;          % Clearing MCU and Serial Port Connection
            %Stopping & Deleting Timers objects
            stop(app.t1);
            stop(app.t2);
            stop(app.t3);
            delete(app.t1);
            delete(app.t2);
            delete(app.t3);
            %Closing App
            delete(app);
        end

        % Menu selected function: SerialPortConfigurationMenu
        function SerialPortConfigurationMenuSelected(app, event)
            app.DialogApp = Serial_Port_Configuration(app,app.SPC_main);
        end

        % Menu selected function: InitializeSPCMenu
        function InitializeSPCMenuSelected2(app, event)
            if app.Initialization_state == 0   
                clear app.MCU
                app.MCU = serialport(app.SPC_main.port, app.SPC_main.baudrate, ...
                    "Parity", app.SPC_main.parity, ...
                    "DataBits",app.SPC_main.databits, ...
                    "StopBits",app.SPC_main.stopbits, ...
                    "FlowControl",app.SPC_main.flowcontrol, ...
                    "ByteOrder",app.SPC_main.byteorder, ...
                    "Timeout",app.SPC_main.timeout);
                
            
                configureTerminator(app.MCU,app.SPC_main.terminator);
                app.MCU.UserData = struct("Title","","Headers","","Ylabels","","Data","START","Count",1);
                configureCallback(app.MCU,"terminator",@readData);
    
                % Drop bytes left in the serial buffer from a previous session.
                flush(app.MCU);
                display("Serial port "+app.SPC_main.port+" is connected");
            end
            app.Initialization_state = 1;
        end

        % Menu selected function: DisconnectSPCMenu
        function DisconnectSPCMenuSelected(app, event)
            clear app.MCU
            app.MCU.UserData.Data ="START";
            display("Serial port "+app.SPC_main.port+" is disconnected");
            app.Initialization_state = 0;
        end

        % Menu selected function: CheckSPCMenu
        function CheckSPCMenuSelected(app, event)
            SPC = app.SPC_main;
            disp(SPC)
        end

        % Menu selected function: DataConfigurationMenu
        function DataConfigurationMenuSelected(app, event)
            app.DialogApp = Data_Configuration(app,app.Data_main);
        end

        % Value changed function: CalculateStatisticsDataCheckBox
        function CalculateStatisticsDataCheckBoxValueChanged(app, event)
            value = app.CalculateStatisticsDataCheckBox.Value;
            app.UITable.Visible = value;
            if value == 1 && app.WatchDataCheckBox.Value == 1
                app.UITable.Data = MonitorGraph.statistics( ...
                    app.MCU.UserData.Data, app.MCU.UserData.Headers, ...
                    2, app.Data_main.graphvariables(1));
            end
        end

        % Value changed function: GridSwitch
        function GridSwitchValueChanged(app, event)
            draw1(app);
        end

        % Value changed function: LegendSwitch
        function LegendSwitchValueChanged(app, event)
            draw1(app);
        end

        % Value changed function: WatchDataCheckBox
        function WatchDataCheckBoxValueChanged(app, event)
            MonitorGraph.setRunning(app.t1, app.WatchDataCheckBox.Value);
        end

        % Value changed function: ColorsEditField
        function ColorsEditFieldValueChanged(app, event)
            app.colors = MonitorGraph.replaceColors(app.ColorsEditField.Value, app.colors);
            draw1(app);
        end

        % Menu selected function: ResetDataMenu
        function ResetDataMenuSelected(app, event)
            % Clear figures and command line
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            clc;
            
            % Delete timers
            stop(app.t1);
            stop(app.t2);
            stop(app.t3);
            delete(app.t1);
            delete(app.t2);
            delete(app.t3);
            
            %Set start value to Guibuttons
            %Watch Data
            app.WatchDataCheckBox.Value = 0;
            app.WatchDataCheckBox_2.Value = 0;
            app.WatchDataCheckBox_3.Value = 0;
            %Calculate Statistics
            app.CalculateStatisticsDataCheckBox.Value = 0;
            app.CalculateStatisticsDataCheckBox_2.Value = 0;
            app.CalculateStatisticsDataCheckBox_3.Value = 0;
            %Grid Switch
            app.GridSwitch.Value = "off";
            app.GridSwitch_2.Value = "off";
            app.GridSwitch_3.Value = "off";
            %Legend Switch
            app.LegendSwitch.Value ="off";
            app.LegendSwitch_2.Value ="off";
            app.LegendSwitch_3.Value ="off";
            %Colors Edit Field
            app.ColorsEditField.Value= "";
            app.ColorsEditField_2.Value= "";
            app.ColorsEditField_3.Value= "";
            %X & Y lim
            app.X_LimEditField.Value= "";
            app.Y_LimEditField.Value= "";
            app.X_LimEditField_2.Value= "";
            app.Y_LimEditField_2.Value= "";
            app.X_LimEditField_3.Value= "";
            app.Y_LimEditField_3.Value= "";
            %Enable Limits
            app.EnableLimitsCheckBox.Value = 0;
            app.EnableLimitsCheckBox_2.Value = 0;
            app.EnableLimitsCheckBox_3.Value = 0;

            % Restart data
            startupFcn(app);
            draw1(app);
            draw2(app);
            draw3(app);
            app.Initialization_state = 0;
        end

        % Button pushed function: SaveasjpgButton
        function SaveasjpgButtonPushed(app, event)
            exportgraphics(app.UIAxes,".\Graph_1.jpg");
        end

        % Button pushed function: SaveascsvButton
        function SaveascsvButtonPushed(app, event)
            MonitorGraph.exportCsv(app.MCU.UserData.Data, app.MCU.UserData.Headers, ...
                2, app.Data_main.graphvariables(1), ".\Graph_1_Data.csv");
        end

        % Value changed function: EnableLimitsCheckBox
        function EnableLimitsCheckBoxValueChanged(app, event)
            value = app.EnableLimitsCheckBox.Value;
            app.X_LimEditField.Editable = value;
            app.Y_LimEditField.Editable = value;
            if value == 0
                
                app.X_LimEditField.Value= "";
                app.Y_LimEditField.Value= "";
            end
            draw1(app)
        end

        % Value changed function: X_LimEditField
        function X_LimEditFieldValueChanged(app, event)
            app.ax1 = MonitorGraph.replacePair(app.X_LimEditField.Value, app.ax1, 0);
            draw1(app);
        end

        % Value changed function: Y_LimEditField
        function Y_LimEditFieldValueChanged(app, event)
            app.ax1 = MonitorGraph.replacePair(app.Y_LimEditField.Value, app.ax1, 2);
            draw1(app);
        end

        % Value changed function: WatchDataCheckBox_2
        function WatchDataCheckBox_2ValueChanged(app, event)
            MonitorGraph.setRunning(app.t2, app.WatchDataCheckBox_2.Value);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.WatchDataCheckBox.Value = 0;
            app.WatchDataCheckBox_2.Value = 0;
            app.WatchDataCheckBox_3.Value = 0;
            stop(app.t1);
            stop(app.t2);
            stop(app.t3);
        end

        % Value changed function: WatchDataCheckBox_3
        function WatchDataCheckBox_3ValueChanged(app, event)
            MonitorGraph.setRunning(app.t3, app.WatchDataCheckBox_3.Value);
        end

        % Value changed function: GridSwitch_2
        function GridSwitch_2ValueChanged(app, event)
            draw2(app);          
        end

        % Value changed function: GridSwitch_3
        function GridSwitch_3ValueChanged(app, event)
            draw3(app);
        end

        % Value changed function: LegendSwitch_2
        function LegendSwitch_2ValueChanged(app, event)
            draw2(app);
        end

        % Value changed function: LegendSwitch_3
        function LegendSwitch_3ValueChanged(app, event)
            draw3(app);
        end

        % Button pushed function: SaveasjpgButton_2
        function SaveasjpgButton_2Pushed(app, event)
            exportgraphics(app.UIAxes_2,".\Graph_2.jpg");
        end

        % Button pushed function: SaveasjpgButton_3
        function SaveasjpgButton_3Pushed(app, event)
            exportgraphics(app.UIAxes_3,".\Graph_3.jpg");
        end

        % Value changed function: ColorsEditField_2
        function ColorsEditField_2ValueChanged(app, event)
            app.colors = MonitorGraph.replaceColors(app.ColorsEditField_2.Value, app.colors);
            draw2(app);
        end

        % Value changed function: ColorsEditField_3
        function ColorsEditField_3ValueChanged(app, event)
            app.colors = MonitorGraph.replaceColors(app.ColorsEditField_3.Value, app.colors);
            draw3(app);
        end

        % Value changed function: EnableLimitsCheckBox_2
        function EnableLimitsCheckBox_2ValueChanged(app, event)
            value = app.EnableLimitsCheckBox_2.Value;
            app.X_LimEditField_2.Editable = value;
            app.Y_LimEditField_2.Editable = value;
            if value == 0
                
                app.X_LimEditField_2.Value= "";
                app.Y_LimEditField_2.Value= "";
            end
            draw2(app)
        end

        % Value changed function: EnableLimitsCheckBox_3
        function EnableLimitsCheckBox_3ValueChanged(app, event)
            value = app.EnableLimitsCheckBox_3.Value;
            app.X_LimEditField_3.Editable = value;
            app.Y_LimEditField_3.Editable = value;
            if value == 0
                
                app.X_LimEditField_3.Value= "";
                app.Y_LimEditField_3.Value= "";
            end
            draw3(app)
        end

        % Value changed function: X_LimEditField_2
        function X_LimEditField_2ValueChanged(app, event)
            app.ax2 = MonitorGraph.replacePair(app.X_LimEditField_2.Value, app.ax2, 0);
            draw2(app);
        end

        % Value changed function: X_LimEditField_3
        function X_LimEditField_3ValueChanged(app, event)
            app.ax3 = MonitorGraph.replacePair(app.X_LimEditField_3.Value, app.ax3, 0);
            draw3(app);
        end

        % Value changed function: Y_LimEditField_2
        function Y_LimEditField_2ValueChanged(app, event)
            app.ax2 = MonitorGraph.replacePair(app.Y_LimEditField_2.Value, app.ax2, 2);
            draw2(app);
        end

        % Value changed function: Y_LimEditField_3
        function Y_LimEditField_3ValueChanged(app, event)
            app.ax3 = MonitorGraph.replacePair(app.Y_LimEditField_3.Value, app.ax3, 2);
            draw3(app);
        end

        % Button pushed function: SaveascsvButton_2
        function SaveascsvButton_2Pushed(app, event)
            earlierCount = sum(app.Data_main.graphvariables(1));
            MonitorGraph.exportCsv(app.MCU.UserData.Data, app.MCU.UserData.Headers, ...
                earlierCount + 2, app.Data_main.graphvariables(2), ".\Graph_2_Data.csv");
        end

        % Button pushed function: SaveascsvButton_3
        function SaveascsvButton_3Pushed(app, event)
            earlierCount = sum(app.Data_main.graphvariables(1:2));
            MonitorGraph.exportCsv(app.MCU.UserData.Data, app.MCU.UserData.Headers, ...
                earlierCount + 2, app.Data_main.graphvariables(3), ".\Graph_3_Data.csv");
        end

        % Value changed function: CalculateStatisticsDataCheckBox_2
        function CalculateStatisticsDataCheckBox_2ValueChanged(app, event)
            value = app.CalculateStatisticsDataCheckBox_2.Value;
            app.UITable_2.Visible = value;
            earlierCount = sum(app.Data_main.graphvariables(1));
            if value == 1 && app.WatchDataCheckBox_2.Value == 1
                app.UITable_2.Data = MonitorGraph.statistics( ...
                    app.MCU.UserData.Data, app.MCU.UserData.Headers, ...
                    earlierCount + 2, app.Data_main.graphvariables(2));
            end
        end

        % Value changed function: CalculateStatisticsDataCheckBox_3
        function CalculateStatisticsDataCheckBox_3ValueChanged(app, event)
            value = app.CalculateStatisticsDataCheckBox_3.Value;
            app.UITable_3.Visible = value;
            earlierCount = sum(app.Data_main.graphvariables(1:2));
            if value == 1 && app.WatchDataCheckBox_3.Value == 1
                app.UITable_3.Data = MonitorGraph.statistics( ...
                    app.MCU.UserData.Data, app.MCU.UserData.Headers, ...
                    earlierCount + 2, app.Data_main.graphvariables(3));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.SerialPortConfigurationMenu = uimenu(app.SettingsMenu);
            app.SerialPortConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @SerialPortConfigurationMenuSelected, true);
            app.SerialPortConfigurationMenu.Text = 'Serial Port Configuration';
            app.DataConfigurationMenu = uimenu(app.SettingsMenu);
            app.DataConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @DataConfigurationMenuSelected, true);
            app.DataConfigurationMenu.Text = 'Data Configuration';
            app.QuitMenu = uimenu(app.SettingsMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.QuitMenu.Text = 'Quit';
            app.SerialPortActionsMenu = uimenu(app.UIFigure);
            app.SerialPortActionsMenu.Text = 'Serial Port Actions';
            app.InitializeSPCMenu = uimenu(app.SerialPortActionsMenu);
            app.InitializeSPCMenu.MenuSelectedFcn = createCallbackFcn(app, @InitializeSPCMenuSelected2, true);
            app.InitializeSPCMenu.Text = 'Initialize SPC';
            app.DisconnectSPCMenu = uimenu(app.SerialPortActionsMenu);
            app.DisconnectSPCMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectSPCMenuSelected, true);
            app.DisconnectSPCMenu.Text = 'Disconnect SPC';
            app.CheckSPCMenu = uimenu(app.SerialPortActionsMenu);
            app.CheckSPCMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckSPCMenuSelected, true);
            app.CheckSPCMenu.Text = 'Check SPC';
            app.ResetMenu = uimenu(app.UIFigure);
            app.ResetMenu.Text = 'Reset';
            app.ResetDataMenu = uimenu(app.ResetMenu);
            app.ResetDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetDataMenuSelected, true);
            app.ResetDataMenu.Text = 'Reset Data';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Graph1Tab = uitab(app.TabGroup);
            app.Graph1Tab.Title = 'Graph 1';
            app.UIAxes = uiaxes(app.Graph1Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TypeColorsseparatedbycommasLabel = uilabel(app.Graph1Tab);
            app.TypeColorsseparatedbycommasLabel.Text = 'Type Colors, separated by commas:';
            app.UITable = uitable(app.Graph1Tab);
            app.UITable.ColumnName = {'Data'; 'Mean'; 'STD'; 'Min'; 'Max'};
            app.UITable.RowName = {};
            app.CalculateStatisticsDataCheckBox = uicheckbox(app.Graph1Tab);
            app.CalculateStatisticsDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateStatisticsDataCheckBoxValueChanged, true);
            app.CalculateStatisticsDataCheckBox.Text = 'Calculate Statistics Data';
            app.WatchDataCheckBox = uicheckbox(app.Graph1Tab);
            app.WatchDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @WatchDataCheckBoxValueChanged, true);
            app.WatchDataCheckBox.Text = 'Watch Data';
            app.SaveasjpgButton = uibutton(app.Graph1Tab, 'push');
            app.SaveasjpgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasjpgButtonPushed, true);
            app.SaveasjpgButton.Text = 'Save as .jpg';
            app.SaveascsvButton = uibutton(app.Graph1Tab, 'push');
            app.SaveascsvButton.ButtonPushedFcn = createCallbackFcn(app, @SaveascsvButtonPushed, true);
            app.SaveascsvButton.Text = 'Save as .csv';
            app.EnableLimitsCheckBox = uicheckbox(app.Graph1Tab);
            app.EnableLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableLimitsCheckBoxValueChanged, true);
            app.EnableLimitsCheckBox.Text = 'Enable Limits';
            app.GridSwitchLabel = uilabel(app.Graph1Tab);
            app.GridSwitchLabel.Text = 'Grid';
            app.GridSwitch = uiswitch(app.Graph1Tab, 'slider');
            app.GridSwitch.Items = {'off', 'on'};
            app.GridSwitch.ValueChangedFcn = createCallbackFcn(app, @GridSwitchValueChanged, true);
            app.GridSwitch.Value = 'off';
            app.LegendSwitchLabel = uilabel(app.Graph1Tab);
            app.LegendSwitchLabel.Text = 'Legend';
            app.LegendSwitch = uiswitch(app.Graph1Tab, 'slider');
            app.LegendSwitch.Items = {'off', 'on'};
            app.LegendSwitch.ValueChangedFcn = createCallbackFcn(app, @LegendSwitchValueChanged, true);
            app.LegendSwitch.Value = 'off';
            app.ColorsEditFieldLabel = uilabel(app.Graph1Tab);
            app.ColorsEditFieldLabel.Text = 'Colors';
            app.ColorsEditField = uieditfield(app.Graph1Tab, 'text');
            app.ColorsEditField.ValueChangedFcn = createCallbackFcn(app, @ColorsEditFieldValueChanged, true);
            app.X_LimEditFieldLabel = uilabel(app.Graph1Tab);
            app.X_LimEditFieldLabel.Text = 'X_Lim';
            app.X_LimEditField = uieditfield(app.Graph1Tab, 'text');
            app.X_LimEditField.ValueChangedFcn = createCallbackFcn(app, @X_LimEditFieldValueChanged, true);
            app.Y_LimEditFieldLabel = uilabel(app.Graph1Tab);
            app.Y_LimEditFieldLabel.Text = 'Y_Lim';
            app.Y_LimEditField = uieditfield(app.Graph1Tab, 'text');
            app.Y_LimEditField.ValueChangedFcn = createCallbackFcn(app, @Y_LimEditFieldValueChanged, true);
            app.Graph2Tab = uitab(app.TabGroup);
            app.Graph2Tab.Title = 'Graph 2';
            app.UIAxes_2 = uiaxes(app.Graph2Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TypeColorsseparatedbycommasLabel_2 = uilabel(app.Graph2Tab);
            app.TypeColorsseparatedbycommasLabel_2.Text = 'Type Colors, separated by commas:';
            app.UITable_2 = uitable(app.Graph2Tab);
            app.UITable_2.ColumnName = {'Data'; 'Mean'; 'STD'; 'Min'; 'Max'};
            app.UITable_2.RowName = {};
            app.CalculateStatisticsDataCheckBox_2 = uicheckbox(app.Graph2Tab);
            app.CalculateStatisticsDataCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CalculateStatisticsDataCheckBox_2ValueChanged, true);
            app.CalculateStatisticsDataCheckBox_2.Text = 'Calculate Statistics Data';
            app.WatchDataCheckBox_2 = uicheckbox(app.Graph2Tab);
            app.WatchDataCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @WatchDataCheckBox_2ValueChanged, true);
            app.WatchDataCheckBox_2.Text = 'Watch Data';
            app.SaveasjpgButton_2 = uibutton(app.Graph2Tab, 'push');
            app.SaveasjpgButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveasjpgButton_2Pushed, true);
            app.SaveasjpgButton_2.Text = 'Save as .jpg';
            app.SaveascsvButton_2 = uibutton(app.Graph2Tab, 'push');
            app.SaveascsvButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveascsvButton_2Pushed, true);
            app.SaveascsvButton_2.Text = 'Save as .csv';
            app.EnableLimitsCheckBox_2 = uicheckbox(app.Graph2Tab);
            app.EnableLimitsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnableLimitsCheckBox_2ValueChanged, true);
            app.EnableLimitsCheckBox_2.Text = 'Enable Limits';
            app.GridSwitch_2Label = uilabel(app.Graph2Tab);
            app.GridSwitch_2Label.Text = 'Grid';
            app.GridSwitch_2 = uiswitch(app.Graph2Tab, 'slider');
            app.GridSwitch_2.Items = {'off', 'on'};
            app.GridSwitch_2.ValueChangedFcn = createCallbackFcn(app, @GridSwitch_2ValueChanged, true);
            app.GridSwitch_2.Value = 'off';
            app.LegendSwitch_2Label = uilabel(app.Graph2Tab);
            app.LegendSwitch_2Label.Text = 'Legend';
            app.LegendSwitch_2 = uiswitch(app.Graph2Tab, 'slider');
            app.LegendSwitch_2.Items = {'off', 'on'};
            app.LegendSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LegendSwitch_2ValueChanged, true);
            app.LegendSwitch_2.Value = 'off';
            app.ColorsEditField_2Label = uilabel(app.Graph2Tab);
            app.ColorsEditField_2Label.Text = 'Colors';
            app.ColorsEditField_2 = uieditfield(app.Graph2Tab, 'text');
            app.ColorsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorsEditField_2ValueChanged, true);
            app.X_LimEditField_2Label = uilabel(app.Graph2Tab);
            app.X_LimEditField_2Label.Text = 'X_Lim';
            app.X_LimEditField_2 = uieditfield(app.Graph2Tab, 'text');
            app.X_LimEditField_2.ValueChangedFcn = createCallbackFcn(app, @X_LimEditField_2ValueChanged, true);
            app.Y_LimEditField_2Label = uilabel(app.Graph2Tab);
            app.Y_LimEditField_2Label.Text = 'Y_Lim';
            app.Y_LimEditField_2 = uieditfield(app.Graph2Tab, 'text');
            app.Y_LimEditField_2.ValueChangedFcn = createCallbackFcn(app, @Y_LimEditField_2ValueChanged, true);
            app.Graph3Tab = uitab(app.TabGroup);
            app.Graph3Tab.Title = 'Graph 3';
            app.UIAxes_3 = uiaxes(app.Graph3Tab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TypeColorsseparatedbycommasLabel_3 = uilabel(app.Graph3Tab);
            app.TypeColorsseparatedbycommasLabel_3.Text = 'Type Colors, separated by commas:';
            app.UITable_3 = uitable(app.Graph3Tab);
            app.UITable_3.ColumnName = {'Data'; 'Mean'; 'STD'; 'Min'; 'Max'};
            app.UITable_3.RowName = {};
            app.CalculateStatisticsDataCheckBox_3 = uicheckbox(app.Graph3Tab);
            app.CalculateStatisticsDataCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CalculateStatisticsDataCheckBox_3ValueChanged, true);
            app.CalculateStatisticsDataCheckBox_3.Text = 'Calculate Statistics Data';
            app.WatchDataCheckBox_3 = uicheckbox(app.Graph3Tab);
            app.WatchDataCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @WatchDataCheckBox_3ValueChanged, true);
            app.WatchDataCheckBox_3.Text = 'Watch Data';
            app.SaveasjpgButton_3 = uibutton(app.Graph3Tab, 'push');
            app.SaveasjpgButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveasjpgButton_3Pushed, true);
            app.SaveasjpgButton_3.Text = 'Save as .jpg';
            app.SaveascsvButton_3 = uibutton(app.Graph3Tab, 'push');
            app.SaveascsvButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveascsvButton_3Pushed, true);
            app.SaveascsvButton_3.Text = 'Save as .csv';
            app.EnableLimitsCheckBox_3 = uicheckbox(app.Graph3Tab);
            app.EnableLimitsCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @EnableLimitsCheckBox_3ValueChanged, true);
            app.EnableLimitsCheckBox_3.Text = 'Enable Limits';
            app.GridSwitch_3Label = uilabel(app.Graph3Tab);
            app.GridSwitch_3Label.Text = 'Grid';
            app.GridSwitch_3 = uiswitch(app.Graph3Tab, 'slider');
            app.GridSwitch_3.Items = {'off', 'on'};
            app.GridSwitch_3.ValueChangedFcn = createCallbackFcn(app, @GridSwitch_3ValueChanged, true);
            app.GridSwitch_3.Value = 'off';
            app.LegendSwitch_3Label = uilabel(app.Graph3Tab);
            app.LegendSwitch_3Label.Text = 'Legend';
            app.LegendSwitch_3 = uiswitch(app.Graph3Tab, 'slider');
            app.LegendSwitch_3.Items = {'off', 'on'};
            app.LegendSwitch_3.ValueChangedFcn = createCallbackFcn(app, @LegendSwitch_3ValueChanged, true);
            app.LegendSwitch_3.Value = 'off';
            app.ColorsEditField_3Label = uilabel(app.Graph3Tab);
            app.ColorsEditField_3Label.Text = 'Colors';
            app.ColorsEditField_3 = uieditfield(app.Graph3Tab, 'text');
            app.ColorsEditField_3.ValueChangedFcn = createCallbackFcn(app, @ColorsEditField_3ValueChanged, true);
            app.X_LimEditField_3Label = uilabel(app.Graph3Tab);
            app.X_LimEditField_3Label.Text = 'X_Lim';
            app.X_LimEditField_3 = uieditfield(app.Graph3Tab, 'text');
            app.X_LimEditField_3.ValueChangedFcn = createCallbackFcn(app, @X_LimEditField_3ValueChanged, true);
            app.Y_LimEditField_3Label = uilabel(app.Graph3Tab);
            app.Y_LimEditField_3Label.Text = 'Y_Lim';
            app.Y_LimEditField_3 = uieditfield(app.Graph3Tab, 'text');
            app.Y_LimEditField_3.ValueChangedFcn = createCallbackFcn(app, @Y_LimEditField_3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end